classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        fxLabel                      matlab.ui.control.Label
        fxField                      matlab.ui.control.EditField
        BchEditFieldLabel            matlab.ui.control.Label
        buochField                   matlab.ui.control.NumericEditField
        ChngitrsaisDropDownLabel     matlab.ui.control.Label
        saisoField                   matlab.ui.control.DropDown
        ChnphngphpDropDownLabel      matlab.ui.control.Label
        phuongphapField              matlab.ui.control.DropDown
        GitrcntnhohmxEditFieldLabel  matlab.ui.control.Label
        giatrixField                 matlab.ui.control.NumericEditField
        KtquEditFieldLabel           matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        TnhtonButton_2               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TnhtonButton_2
        function TnhtonButton_2Pushed(app, event)
            fx = @(x)asin(x);
            
            h = app.buochField.Value;
            saiso = str2num(app.saisoField.Value);
            phuongphap = str2num(app.phuongphapField.Value);
            x = app.giatrixField.Value;
            output = 0;


            switch(phuongphap)
                case 0
                    switch(saiso)
                        
                        case 0
                            xn = x:h:(x + 4*h);
                            y = feval(fx, xn);
                            f_x = y(1);
                            f_plus_h = y(2);
                            output = ((-f_x) + f_plus_h)/(h);
                        
                        case 1
                            xn = x:h:(x + 4*h);
                            y = feval(fx, xn);
                            f_x = y(1);
                            f_plus_h = y(2);
                            f_plus_2h = y(3);
                            output = ((-3*f_x) + 4*f_plus_h -f_plus_2h)/(2*h);
                    end
                    
                case 1
                    switch(saiso)
                        
                        case 0
                            xn = (x-4*h):h:x;
                            y = feval(fx, xn);
                            f_x = y(5);
                            f_minor_h = y(4);
                            output = (-f_minor_h + f_x) / (h);
                        
                        case 1
                            xn = (x-4*h):h:x;
                            y = feval(fx, xn);
                            f_x = y(5);
                            f_minor_h = y(4);
                            f_minor_2h = y(3);
                            output = (f_minor_2h - 4*f_minor_h + 3*f_x) / (2*h);
                    end
                    
                case 2
                    switch (saiso)
                        case 0
                            output = 0;
                        case 1 
                            xn = (x - 2*h):h:(x + 2*h);
                            y = feval(fx, xn);
                            f_minus_h = y(2);
                            f_plus_h = y(4);
                            output = ((-f_minus_h) + f_plus_h) / (2*h);
                            
                     end
            end
            
            if(output == 0)
                 app.EditField.Value = 'Khong ho tro tinh toan xap xi trung tam voi O(h)';   
            else 
               app.EditField.Value = num2str(output); 
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.fxLabel = uilabel(app.UIFigure);
            app.fxLabel.Text = 'f(x) =';
            app.fxField = uieditfield(app.UIFigure, 'text');
            app.fxField.Value = 'asin(x)';
            app.BchEditFieldLabel = uilabel(app.UIFigure);
            app.BchEditFieldLabel.Text = 'Bước h = ';
            app.buochField = uieditfield(app.UIFigure, 'numeric');
            app.buochField.Value = 0.2;
            app.ChngitrsaisDropDownLabel = uilabel(app.UIFigure);
            app.ChngitrsaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.saisoField = uidropdown(app.UIFigure);
            app.saisoField.Items = {'O(h)', 'O(h^2)'};
            app.saisoField.ItemsData = {'0', '1'};
            app.saisoField.Value = '1';
            app.ChnphngphpDropDownLabel = uilabel(app.UIFigure);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.phuongphapField = uidropdown(app.UIFigure);
            app.phuongphapField.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.phuongphapField.ItemsData = {'0', '1', '2'};
            app.phuongphapField.Value = '2';
            app.GitrcntnhohmxEditFieldLabel = uilabel(app.UIFigure);
            app.GitrcntnhohmxEditFieldLabel.Text = 'Giá trị cần tính đạo hàm (x)';
            app.giatrixField = uieditfield(app.UIFigure, 'numeric');
            app.giatrixField.Value = 0.5;
            app.KtquEditFieldLabel = uilabel(app.UIFigure);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.TnhtonButton_2 = uibutton(app.UIFigure, 'push');
            app.TnhtonButton_2.ButtonPushedFcn = createCallbackFcn(app, @TnhtonButton_2Pushed, true);
            app.TnhtonButton_2.Text = 'Tính toán';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end